classdef binari < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Image2         matlab.ui.control.Image
        Image          matlab.ui.control.Image
        computeButton  matlab.ui.control.StateButton
        loadButton     matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: computeButton
        function computeButtonValueChanged(app, event)
            value = app.computeButton.Value;
            
            if isempty(app.Image.ImageSource)
                disp('No image loaded');
            else
                % 画像の取得
                im = app.Image.ImageSource;
                % 画像がchar（ファイルパス）の場合、読み込み
                if ischar(im)
                    im = imread(im);
                end
                % グレースケール変換
                imgray = rgb2gray(im);
                % 二値化
                BW = imbinarize(imgray);

                % 二値化画像を0または1から0または255に変換
                BW = uint8(BW) * 255;
                % 二値化画像をRGB画像に変換
                BW_RGB = cat(3, BW, BW, BW); % 3チャネルにコピー
                % 二値化画像の表示
                app.Image2.ImageSource = BW_RGB;
            end

        end

        % Value changed function: loadButton
        function loadButtonValueChanged(app, event)
            value = app.loadButton.Value;
                        
            % ファイル選択ダイアログを開く
            [filename, filepath] = uigetfile('/MATLAB Drive/Camera Roll/logo_csse.png', 'Select an image file');
            if isequal(filename, 0)
                disp('File selection canceled');
            else
                % ファイルの完全なパスを作成
                fullFileName = fullfile(filepath, filename);
                % 画像の読み込み
                im = imread(fullFileName);
                % 画像の表示
                %imshow(im);
                app.Image.ImageSource = im;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadButton = uibutton(app.UIFigure, 'state');
            app.loadButton.ValueChangedFcn = createCallbackFcn(app, @loadButtonValueChanged, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'state');
            app.computeButton.ValueChangedFcn = createCallbackFcn(app, @computeButtonValueChanged, true);
            app.computeButton.Text = 'compute';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binari

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end